classdef excel_import_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ShowCorrelationCheckBox  matlab.ui.control.CheckBox
        UITable                  matlab.ui.control.Table
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function update_plot(app)
            % retrieve new data from edited table
            t = app.UITable.DisplayData;

            % update plot with new data
            x2 = t.Age;
            y2 = t.Sleep;
            plot(app.UIAxes,x2,y2,"o");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %import data from excel sheet 
            t = readtable("sleepstudy1.xlsx","ReadRowNames",false);
            vars = {'Age','ClassYear','Sleep','SelfReportHappiness'};
            t = t(1:18,vars);
            
            % sort data by age
            t = sortrows(t,"Age");

            % display data in table
            app.UITable.Data = t;
            
            % store age vs sleep data in plot
            x1 = app.UITable.Data.Age;
            y1 = app.UITable.Data.Sleep;
            plot(app.UIAxes,x1,y1,"o");
            
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            update_plot(app);
        end

        % Callback function: ShowCorrelationCheckBox, UIAxes
        function ShowCorrelationCheckBoxValueChanged(app, event)
            value = app.ShowCorrelationCheckBox.Value;

            x1 = app.UITable.Data.Age;
            y1 = app.UITable.Data.Sleep;
            if value == true

            p = polyfit( x1, y1, 1); %line of best fit to 1st degree
            f = polyval (p, x1);
            slope = round(p(1), 3);


            plot(app.UIAxes, x1, y1, 'o', x1, f, 'm-');
            
            legend(app.UIAxes,'Data Points', 'Trend Line')
            str = "Slope is " + slope;

            text(app.UIAxes, 23,6, str)
            else
            plot(app.UIAxes, x1, y1, 'o');
            legend(app.UIAxes,'Data Points')
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sleep vs. Age')
            xlabel(app.UIAxes, 'Age')
            ylabel(app.UIAxes, 'Sleep')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @ShowCorrelationCheckBoxValueChanged, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Age'; 'Class Year'; 'Sleep'; 'Happiness'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.ShowCorrelationCheckBox = uicheckbox(app.UIFigure);
            app.ShowCorrelationCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowCorrelationCheckBoxValueChanged, true);
            app.ShowCorrelationCheckBox.Text = 'Show Correlation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = excel_import_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end