classdef excel_import_test_in_progress < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        DataShownDropDown        matlab.ui.control.DropDown
        DataShownDropDownLabel   matlab.ui.control.Label
        ShowCorrelationCheckBox  matlab.ui.control.CheckBox
        UITable                  matlab.ui.control.Table
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function create_plot(app)
            %changes app title when drop down value changes
            graph = app.UIAxes;
            graph_title = app.DataShownDropDown.Value;
            app.UIAxes.Title.String = graph_title;
        end

        function change_columns(app)

            I = app.DataShownDropDown.Items;
            
            vector_1 = strcmp(I, 'Age vs Sleep');
            vector_2 = strcmp(I, 'Age vs Class Year');
            vector_3 = strcmp(I, 'Age vs Happiness');
            vector_4 = strcmp(I, 'Sleep vs Happiness');

            main_vector = [vector_1; vector_2; vector_3; vector_4];
            vec = main_vector(1:16);


            t = app.UITable.DisplayData;
            x2 = t.Age;
            y2 = t.Sleep;
            y3 = t.ClassYear;
            y4 = t.SelfReportHappiness;
            for ii = 1:16
                while ii < 5
                if vec(ii) == 1
                       plot(app.UIAxes,x2,y2,"o");
                       break
                end
                end
            
                while ii < 9
                    if vec(ii) == 1
                       plot(app.UIAxes, x2, y3, "square");
                       break
                    end
                end
                while ii < 13
            
                   if vec(ii) == 1
                       plot(app.UIAxes, x2, y4, ".");
                       break
                   end
                end
            
                   if vector_4(4) == 1
                       plot(app.UIAxes, y2, y4, "*");
                   end
            end
        end


        function update_plot(app)
            % retrieve new data from edited table
            t = app.UITable.DisplayData;

            % update plot with new data
            x2 = t.Age;
            y2 = t.Sleep;
            plot(app.UIAxes,x2,y2,"o");

            y3 = t.ClassYear;
            y4 = t.SelfReportHappiness;

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %import data from excel sheet 
            t = readtable("sleepstudy1.xlsx","ReadRowNames",false);
            vars = {'Age','ClassYear','Sleep','SelfReportHappiness'};
            t = t(1:18,vars);
            
            % sort data by age
            t = sortrows(t,"Age");

            % display data in table
            app.UITable.Data = t;
            
            % store age vs sleep data in plot
            x1 = app.UITable.Data.Age;
            y1 = app.UITable.Data.Sleep;
            plot(app.UIAxes,x1,y1,"o");

            app.DataShownDropDown.Value = app.UIAxes.Title.String;
        end

        % Callback function: ShowCorrelationCheckBox, UIAxes
        function ShowCorrelationCheckBoxValueChanged(app, event)
            value = app.ShowCorrelationCheckBox.Value;

            x1 = app.UITable.Data.Age;
            y1 = app.UITable.Data.Sleep;

            if value == true

            p = polyfit( x1, y1, 1); %line of best fit to 1st degree
            f = polyval (p, x1);

            plot(app.UIAxes, x1, y1, 'o', x1, f, '-');
         
            else
            plot(app.UIAxes,x1,y1,"o");
            end

        end

        % Value changed function: DataShownDropDown
        function DataShownDropDownValueChanged(app, event)
            value = app.DataShownDropDown.Value;
            create_plot(app);
            change_columns(app);

        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            newDisplayData = app.UITable.DisplayData;
            update_plot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Age vs Sleep')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @ShowCorrelationCheckBoxValueChanged, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Age'; 'Class Year'; 'Sleep'; 'Happiness'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.ShowCorrelationCheckBox = uicheckbox(app.UIFigure);
            app.ShowCorrelationCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowCorrelationCheckBoxValueChanged, true);
            app.ShowCorrelationCheckBox.Text = 'Show Correlation';
            app.DataShownDropDownLabel = uilabel(app.UIFigure);
            app.DataShownDropDownLabel.Text = 'Data Shown';
            app.DataShownDropDown = uidropdown(app.UIFigure);
            app.DataShownDropDown.Items = {'Age vs Sleep', 'Age vs Class Year', 'Age vs Happiness', 'Sleep vs Happiness'};
            app.DataShownDropDown.ValueChangedFcn = createCallbackFcn(app, @DataShownDropDownValueChanged, true);
            app.DataShownDropDown.Value = 'Age vs Sleep';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = excel_import_test_in_progress

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end