classdef real_data_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TextArea                    matlab.ui.control.TextArea
        Image                       matlab.ui.control.Image
        DisplayTrendlineCheckBox    matlab.ui.control.CheckBox
        DisplayExplanationCheckBox  matlab.ui.control.CheckBox
        Summary                     matlab.ui.control.TextArea
        UITable                     matlab.ui.control.Table
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %import data from excel sheet 
            t = readtable("SleepTable.xlsx","ReadRowNames",false);
            vars = {'TotSleepTime','CORT_BL','CORT_SD'};
            t = t(1:18,vars);

            % calculate change in cortisol levels
            % DELTA_CORT = zeros(height(t),1);
            bl_array = t.CORT_BL; %zeros(height(t),1);
            sd_array = t.CORT_SD; %zeros(height(t),1);
            % bl_array = t(1:18,'CORT_BL');
            % sd_array = t(1:18,'CORT_SD');
            DELTA_CORT = bl_array - sd_array;
            % t.DELTA_CORT = app.UITable.Data.CORT_BL - app.UITable.Data.CORT_SD;
            t.('DELTA_CORT') = DELTA_CORT; %(t((1:18),CORT_BL) - t((1:18),CORT_SD));

            
            % sort data by age
            t = sortrows(t,"TotSleepTime");

            % display data in table
            app.UITable.Data = t;
            
            % store age vs sleep data in plot
            x1 = app.UITable.Data.TotSleepTime;
            y1 = app.UITable.Data.DELTA_CORT;
            plot(app.UIAxes,x1,y1,"o");
        end

        % Value changed function: Summary
        function display_text(app, event)
           app.Summary.Value = 'This study displays how sleep deprivation can lead to elevated cortisol levels. The cortisol levels measured the morning after subjects with no sleep compared to subjects who had normal sleep, were much lower. This is due to a variety of reasons, such as the Hypothalamic-Pituitary-Adrenal (HPA) Axis Response. The study that we found discusses how the HPA axis is involved in cortisol secretion. Sleep deprivation can activate the HPA axis which can cause an initial surge in cortical levels during the night time. However, this increase in cortisol levels can desensitize the HPA axis, resulting in blunted cortisol responses, and therefore lower cortisol levels in the morning. This dysregulation in cortisol secretion can have profound effects on various physiological processes, including immune function, metabolism, and brain function.';
            
        end

        % Value changed function: DisplayExplanationCheckBox
        function DisplayExplanationCheckBoxValueChanged(app, event)
            value = app.DisplayExplanationCheckBox.Value;
            if value 
                app.Summary.Value = 'This study displays how sleep deprivation can lead to elevated cortisol levels. The cortisol levels measured the morning after subjects with no sleep compared to subjects who had normal sleep, were much lower. This is due to a variety of reasons, such as the Hypothalamic-Pituitary-Adrenal (HPA) Axis Response. The study that we found discusses how the HPA axis is involved in cortisol secretion. Sleep deprivation can activate the HPA axis which can cause an initial surge in cortical levels during the night time. However, this increase in cortisol levels can desensitize the HPA axis, resulting in blunted cortisol responses, and therefore lower cortisol levels in the morning. This dysregulation in cortisol secretion can have profound effects on various physiological processes, including immune function, metabolism, and brain function.';
            else
                app.Summary.Value = '';
            end
        end

        % Value changed function: DisplayTrendlineCheckBox
        function DisplayTrendlineCheckBoxValueChanged(app, event)
            value = app.DisplayTrendlineCheckBox.Value;

            x1 = app.UITable.Data.TotSleepTime;
            y1 = app.UITable.Data.DELTA_CORT;

            if value == true

            p = polyfit( x1, y1, 1); %line of best fit to 1st degree
            f = polyval (p, x1);
            slope = round(p(1), 3);

            plot(app.UIAxes, x1, y1, 'o', x1, f, '-');
            
            legend(app.UIAxes,'Data Points', 'Trend Line')
            str = "Slope is " + slope;

            text(app.UIAxes, 7.75,0.38, str)
            else
            plot(app.UIAxes, x1, y1, 'o');
            legend(app.UIAxes,'Data Points')
         
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Baseline Sleep vs. Decrease in Cortisol after Sleep Deprivation')
            xlabel(app.UIAxes, 'Baseline Sleep (Hours)')
            ylabel(app.UIAxes, 'Decrease in Cortisol Levels')
            colormap(app.UIAxes, 'colorcube')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Baseline Sleep (Hours)'; 'Cortisol (Baseline)'; 'Cortisol (Sleep Deprivation)'; '∆ Cortisol'};
            app.UITable.ColumnRearrangeable = 'on';
            app.UITable.RowName = {};
            app.Summary = uitextarea(app.UIFigure);
            app.Summary.ValueChangedFcn = createCallbackFcn(app, @display_text, true);
            app.DisplayExplanationCheckBox = uicheckbox(app.UIFigure);
            app.DisplayExplanationCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayExplanationCheckBoxValueChanged, true);
            app.DisplayExplanationCheckBox.Text = 'Display Explanation';
            app.DisplayTrendlineCheckBox = uicheckbox(app.UIFigure);
            app.DisplayTrendlineCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayTrendlineCheckBoxValueChanged, true);
            app.DisplayTrendlineCheckBox.Text = 'Display Trendline';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'koala_image.jpg');
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Sleep Deprivation''s Affect'; 'on Physiological Processes; Examining Cortisol Levels '};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = real_data_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end